classdef watermarker2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        HomeTab                         matlab.ui.container.Tab
        WatermarkerLabel                matlab.ui.control.Label
        homeTab_label_loading           matlab.ui.control.Label
        EmbedTab                        matlab.ui.container.Tab
        embedTab_display_watermark      matlab.ui.control.UIAxes
        embedTab_display_hostimage      matlab.ui.control.UIAxes
        embedTab_display_watermarkedimage  matlab.ui.control.UIAxes
        embedTab_textbox                matlab.ui.control.TextArea
        embedTab_button_loadWatermark   matlab.ui.control.Button
        embedTab_button_loadHost        matlab.ui.control.Button
        embedTab_button_embedWatermark  matlab.ui.control.Button
        embedTab_AverageembeddingtimesecLabel  matlab.ui.control.Label
        embedTab_label_averagetime      matlab.ui.control.Label
        embedTab_UsingLabel             matlab.ui.control.Label
        embedTab_label_algorithm        matlab.ui.control.Label
        EmbeddingAlgorithmDropDownLabel  matlab.ui.control.Label
        embedTab_dropdown_embeddingAlgorithm  matlab.ui.control.DropDown
        button_saveEmbedded_embed       matlab.ui.control.Button
        FileTypeDropDownLabel           matlab.ui.control.Label
        dropdown_fileType_embed         matlab.ui.control.DropDown
        TrialsLabel                     matlab.ui.control.Label
        spinner_trials_embed            matlab.ui.control.Spinner
        TotalembeddingtimesecLabel      matlab.ui.control.Label
        label_totaltime_embed           matlab.ui.control.Label
        ShowAdvancedStatsSwitchLabel    matlab.ui.control.Label
        switch_advancedStats_embed      matlab.ui.control.ToggleSwitch
        TilingStrengthLabel             matlab.ui.control.Label
        knob_embeddingStrength_embed    matlab.ui.control.DiscreteKnob
        label_watermarkSize_embed       matlab.ui.control.Label
        label_hostSize_embed            matlab.ui.control.Label
        PixelsLabel                     matlab.ui.control.Label
        PixelsLabel_2                   matlab.ui.control.Label
        ExtractTab                      matlab.ui.container.Tab
        display_extracted_extract       matlab.ui.control.UIAxes
        display_watermarkedimage_extract  matlab.ui.control.UIAxes
        button_extractWatermark_extract  matlab.ui.control.Button
        textbox_extract                 matlab.ui.control.TextArea
        ExtractingAlgorithmLabel        matlab.ui.control.Label
        dropdown_extract                matlab.ui.control.DropDown
        button_loadEmbedded_extract     matlab.ui.control.Button
        ExtractiontimesecLabel          matlab.ui.control.Label
        label_time_extract              matlab.ui.control.Label
        UsingLabel_2                    matlab.ui.control.Label
        label_algorithm_extract         matlab.ui.control.Label
        result_savewatermark_extract    matlab.ui.control.Label
        button_saveExtracted_extract    matlab.ui.control.Button
        FileTypeDropDownLabel_2         matlab.ui.control.Label
        dropdown_fileType_extract       matlab.ui.control.DropDown
        label_embeddedSize_extract      matlab.ui.control.Label
        PixelsLabel_3                   matlab.ui.control.Label
        label_extractedSize_extract     matlab.ui.control.Label
        PixelsLabel_4                   matlab.ui.control.Label
        AttacksTab                      matlab.ui.container.Tab
        button_jpegAttack_attack        matlab.ui.control.Button
        JPEGCompressionLabel            matlab.ui.control.Label
        button_noiseAttack_attack       matlab.ui.control.Button
        NoiseAttackLabel                matlab.ui.control.Label
        button_croppingAttack_attack    matlab.ui.control.Button
        CroppingLabel                   matlab.ui.control.Label
        display_attackedImage_attack    matlab.ui.control.UIAxes
        display_embeddedImage_attack    matlab.ui.control.UIAxes
        button_loadOriginal_attack      matlab.ui.control.Button
        button_saveAttacked_attack      matlab.ui.control.Button
        button_compare_attack           matlab.ui.control.Button
        ComparisonTab                   matlab.ui.container.Tab
        comparisonTab_display_image1    matlab.ui.control.UIAxes
        comparisonTab_display_image2    matlab.ui.control.UIAxes
        comparisonTab_button_loadImage1  matlab.ui.control.Button
        comparisonTab_button_loadImage2  matlab.ui.control.Button
        LocalImagesDropDownLabel        matlab.ui.control.Label
        comparisonTab_dropdown_loadlocal1  matlab.ui.control.DropDown
        comparisonTab_button_loadlocal1  matlab.ui.control.Button
        LocalImagesDropDown_2Label      matlab.ui.control.Label
        comparisonTab_dropdown_loadlocal2  matlab.ui.control.DropDown
        comparisonTab_button_loadlocal2  matlab.ui.control.Button
        comparisonTab_label_loadlocal1  matlab.ui.control.Label
        comparisonTab_label_loadlocal2  matlab.ui.control.Label
        comparisonTab_button_compare    matlab.ui.control.Button
        comparisonTab_display_output    matlab.ui.control.UIAxes
        stats                           matlab.ui.control.Label
    end

    
    properties (Access = private)
        fHost = AppImage;         % Global host AppImage
        fWatermark = AppImage;    % Global watermark AppImage
        fEmbedded = AppImage;     % Global embedded AppImage
        fExtracted = AppImage;
        fImage1 = AppImage;
        fImage2 = AppImage;
    end
    
    methods (Access = private)
        
        function results = updateStats(app)
            lHostImage = app.fHost;
            lWatermarkImage = app.fWatermark;
            lEmbeddedImage = app.fEmbedded;
            lExtractedImage = app.fExtracted;
            lImage1Image = app.fImage1;
            lImage2Image = app.fImage2;
            
            lHost = lHostImage.matrix;
            lWatermark = lWatermarkImage.matrix;
            lEmbedded = lEmbeddedImage.matrix;
            lExtracted = lExtractedImage.matrix;
            lImage1 = lImage1Image.matrix;
            lImage2 = lImage2Image.matrix;
            
            text = "";
            
            if lHost ~= 0
                text = strcat(text, "H ");
            end
            if lWatermark ~= 0
                text = strcat(text, "WM ");
            end
            if lEmbedded ~= 0
                text = strcat(text, "Em ");
            end
            if lExtracted ~=0
                text = strcat(text, "Ex ");
            end
            if lImage1 ~= 0
                text = strcat(text, "I1 ");
            end
            if lImage2 ~= 0
                text = strcat(text, "I2 ");
            end
            if text ~= ""
                app.stats.Text = text;
                disp(text);
                results = 1;
            else
                results = 0;
            end
            
        end
        
        function success = loadAppImage(app, aImageType)
            [lFile, lPath] = uigetfile('', 'Select an image');
            if isequal(lFile, 0)
                disp('User selected cancel');
                success = false;
            else
                lImageType = aImageType;
                lImage = fullfile(lPath, lFile);
                lImage = imread(lImage);
                lAppImage = AppImage(lImage, lImageType);
                disp(strcat('Property loaded: ', lImageType));
                
                % check whether loaded image is grayscale
                if (size(lImage, 3) ~= 1)
                    lAppImage = lAppImage.makeGrayscale;
                end
                
                [row, column] = size(lImage);
                if (row - column ~= 0)
                    lAppImage = lAppImage.makeSquare(128);
                end
                
                if isequal(lImageType, 'Watermark')
                    app.fWatermark = lAppImage.makeBinary;
                end
                if isequal(lImageType, 'Host')
                    app.fHost = lAppImage;
                end
                if isequal(lImageType, 'Embedded')
                    app.fEmbedded = lAppImage;
                end
                if isequal(lImageType, 'Extracted')
                    app.fExtracted = lAppImage;
                end
                if isequal(lImageType, 'Image1')
                    app.fImage1 = lAppImage;
                end
                if isequal(lImageType, 'Image2')
                    app.fImage2 = lAppImage;
                end
                if updateStats(app) == true
                    disp('Stats updated: loadAppImage');
                end
                success = true;
            end
        end
        
        function result = saveImage(app, aProperty, aFType)
            lFType = aFType;
            lProperty = aProperty;
            lImage = 0;
            switch lProperty
                case 'extracted'
                    lImage = app.gExtracted;
                case 'embedded'
                    lImage = app.gEmbedded;
                case 'host'
                    lImage = app.gHost;
                case 'watermark'
                    lImage = app.gWatermark;
                case 'image1'
                    lImage = app.gImage1;
                case 'image2'
                    lImage = app.gImage2;
                otherwise
                    % do nothing
                    disp('Error: saveImage - CRITICAL: aProperty is not properly defined');
            end
            lFileName = strcat(lProperty, 'Image.', lFType);
            try
                imwrite(lImage, lFileName);
            catch
                disp('Error: saveImage - FAILED: imwrite failed to execute');
            end
            
            
            fileType = app.dropdown_fileType_extract.Value;
            lImage = uint8(app.gExtracted);
            switch fileType
                case '0'
                    % no file type selected
                    disp('Error: Save Button Pushed');
                    disp('No file type selected');
                case '1'
                    % JPEG
                    imwrite(lImage, 'extractedWatermark.jpeg');
                    disp('Watermarked Image Saved Successfully: jpeg');
                case '2'
                    % TIFF
                    imwrite(lImage, 'extractedWatermark.tif');
                    disp('Watermarked Image Saved Successfully: tif');
                case '3'
                    % BMP
                    imwrite(lImage, 'extractedWatermark.bmp');
                    disp('Watermarked Image Saved Successfully: bmp');
                case '4'
                    % PNG
                    imwrite(lImage, 'extractedWatermark.png');
                    disp('Watermarked Image Saved Successfully: png');
                otherwise
                    % error
                    disp('Error: save_extractButtonPushed critical error');
            end
            result = true;
        end
        
        function result = saveAppImage(app, aProperty, aFType)
            lFType = aFType;
            lProperty = aProperty;
            lImage = 0;
            switch lProperty
                case 'extracted'
                    lImage = app.fExtracted;
                case 'embedded'
                    lImage = app.fEmbedded;
                case 'host'
                    lImage = app.fHost;
                case 'watermark'
                    lImage = app.fWatermark;
                case 'image1'
                    lImage = app.fImage1;
                case 'image2'
                    lImage = app.fImage2;
                otherwise
                    % do nothing
                    disp('Error: saveImage - CRITICAL: aProperty is not properly defined');
            end
            lFileName = strcat(lProperty, 'Image.', lFType);
            try
                imwrite(lImage.matrix, lFileName);
                result = true;
            catch
                disp('Error: saveImage - FAILED: imwrite failed to execute');
                result = false;
            end
        end
        
        function refreshAllTabs(app)
            app.homeTab_label_loading.Text = 'Loading...';
            outputText = '';
            
            if embedTabRefresh(app) == true
                outputText = strcat(outputText, "Embed ");
                %disp('Embed tab refreshed');
            end
            if extractTabRefresh(app) == true
                outputText = strcat(outputText, "Extract ");
                %disp('Extract tab refreshed');
            end
            if attacksTabRefresh(app) == true
                outputText = strcat(outputText, "Attacks ");
                %disp('Attacks tab refreshed');
            end
            if comparisonTabRefresh(app) == true
                outputText = strcat(outputText, "Comparison ");
                %disp('Comparison tab refreshed');
            end
            if homeTabRefresh(app) == true
                %disp('Home tab refreshed');
            end
            if strcmp(outputText, '')
                disp('refresh All: no change');
            else
                disp(strcat(outputText, 'Tabs updated'));
            end
            
        end
        
        function results = homeTabRefresh(app)
            app.homeTab_label_loading.Text = 'Ready!';
            results = true;
        end
        
        % refreshes the embedd tab with updated results
        function results = embedTabRefresh(app)
            %try
            lHostImage = app.fHost;
            lWatermarkImage = app.fWatermark;
            lEmbeddedImage = app.fEmbedded;
            
            lHost = lHostImage.matrix;
            lWatermark = lWatermarkImage.matrix;
            lEmbedded = lEmbeddedImage.matrix;
            
            % update text box
            value = app.embedTab_dropdown_embeddingAlgorithm.Value;
            switch value
                case '0'
                    app.embedTab_textbox.Value = 'Select an embedding method from the dropdown';
                case '1'
                    % describe LSB
                    text = 'The LSB (Least significant bit) algorithm breaks down the host image into its component bitplanes and embedds the watermark as a binary image into the lest significan bit of the host image.';
                    app.embedTab_textbox.Value = text;
                case '2'
                    % describe DCT
                    text = "The DCT (Discrete Cosine Transform) algorithm uses DCT to embed the watermark into the host image";
                    app.embedTab_textbox.Value = text;
                case '3'
                    % describe SVD
                    text = 'The SVD (Singular Value Decomposition) algorithm uses SVD to embed the watermark into the host image';
                    app.embedTab_textbox.Value = text;
                otherwise
                    app.embedTab_textbox.Value = 'Error';
            end
            
            % update image displays
            app.embedTab_display_hostimage.Title.String = strcat(lHostImage.title, ' (Host)');
            app.embedTab_display_watermark.Title.String = strcat(lWatermarkImage.title, ' (Watermark)');
            app.embedTab_display_watermarkedimage.Title.String = strcat(lEmbeddedImage.title, ' (Embedded)');
            imshow(lHost,       [], 'Parent', app.embedTab_display_hostimage);
            imshow(lWatermark,  [], 'Parent', app.embedTab_display_watermark);
            imshow(lEmbedded,   [], 'Parent', app.embedTab_display_watermarkedimage);
            
            % setting image size labels
            app.label_hostSize_embed.Text = lHostImage.dimensions;
            app.label_watermarkSize_embed.Text = lWatermarkImage.dimensions;
            results = true;
            drawnow;
            %catch
            %results = false;
            %end
            
        end
        
        % refreshes the extract tab with updated results
        function results = extractTabRefresh(app)
            %lWatermark = app.gWatermark;
            lEmbeddedImage = app.fEmbedded;
            lExtractedImage = app.fExtracted;
            lEmbedded = lEmbeddedImage.matrix;
            lExtracted = lExtractedImage.matrix;
            
            % update displays
            imshow(lEmbedded,   [], 'Parent', app.display_watermarkedimage_extract);
            imshow(lExtracted,  [], 'Parent', app.display_extracted_extract);
            
            % update labels
            app.label_embeddedSize_extract.Text = lEmbeddedImage.dimensions;
            app.label_extractedSize_extract.Text = lExtractedImage.dimensions;
            results = true;
            
            drawnow;
        end
        
        function results = attacksTabRefresh(app)
            % do some shit - TODO
            
            results = true;
            drawnow;
        end
        
        function results = comparisonTabRefresh(app)
            lImage1Image = app.fImage1;
            lImage2Image = app.fImage2;
            lImage1 = lImage1Image.matrix;
            lImage2 = lImage2Image.matrix;
            
            % update displays
            imshow(lImage1, [], 'Parent', app.comparisonTab_display_image1);
            imshow(lImage2, [], 'Parent', app.comparisonTab_display_image2);
            
            results = true;
            drawnow;
        end
        
        function lDisplay = dispAdj(app, aDisplay)
            lDisplay = aDisplay;
            original = lDisplay.Position;
            adjusted = [original(1,1) + 50, original(1,2), 250, 250];
            lDisplay.Position = adjusted;
        end


    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            init;
            lWatermark = watermark;
            lHost = host;
            lWatermarkImage = AppImage(lWatermark, 'Watermark');
            lWatermarkImage = lWatermarkImage.makeBinary;
            app.fWatermark = lWatermarkImage;
            app.fHost = AppImage(lHost, 'Host');
            refreshAllTabs(app);
        end

        % Button pushed function: embedTab_button_loadWatermark
        function embedTab_button_loadWatermarkPushed(app, event)
            result = loadAppImage(app, 'Watermark');
            if result == true
                if embedTabRefresh(app) == true
                    disp('Embed tab updated');
                end
            else
                disp('Error: button_loadWatermark_embedPushed - FAILED: loadAppImage returned false');
            end
        end

        % Button pushed function: embedTab_button_loadHost
        function embedTab_button_loadHostPushed(app, event)
            result = loadAppImage(app, 'Host');
            if result == true
                if embedTabRefresh(app) == true
                    disp('Embed tab updated');
                end
                % imshow(app.gHost, [], 'Parent', app.embedTab_display_hostimage);
            else
                disp('Error: button_loadHost_embedPushed - FAILED: loadAppImage returned false');
            end
        end

        % Value changed function: embedTab_dropdown_embeddingAlgorithm
        function dropdown_embedValueChanged(app, event)
            if embedTabRefresh(app) == true
                disp('Embed Tab Refreshed');
            end
        end

        % Button pushed function: embedTab_button_embedWatermark
        function embedTab_button_embedWatermarkPushed(app, event)
            lHostImage = app.fHost;
            lWatermarkImage = app.fWatermark;
            lHost = lHostImage.matrix;
            lWatermark = lWatermarkImage.matrix;
            
            value = app.embedTab_dropdown_embeddingAlgorithm.Value;
            lTimeElapsed_total = 0;
            lTimeElapsed = 0;
            lResult = 0;
            lEmbeddingIntensity = app.knob_embeddingStrength_embed.Value;   % Used to set block size in some embedding algorithms
            switch lEmbeddingIntensity
                case 0
                    % error
                    disp('Error: button_embedWatermark_embedPushed - CRITICAL: lEmbeddingIntensity == 0');
                case '1'
                    % Off
                    lBlockSize = [512,512];
                case '2'
                    % Low
                    lBlockSize = [256,256];
                case '3'
                    % High
                    lBlockSize = [64,64];
                otherwise
                    % error
                    disp('Error: button_embedWatermark_embedPushed - CRITICAL: lEmbeddingIntensity is out of range');
            end
            disp('Embedding Intensity:');
            disp(lEmbeddingIntensity);
            lTrials = app.spinner_trials_embed.Value;
            lTimeElapsedArr = [1,lTrials];       % array containing each trial's time. for plotting
            for i = 1 : lTrials
                switch value
                    case '0'
                        % No embedding technique selected
                        disp('Error: Please select a watermarking algorithm from the dropdown menu');
                    case '1'
                        % embed using LSB
                        %[lWatermarkedImage, lTimeElapsed, lResult] = BitplaneEmbed(lHost, lWatermark);
                        %[lWatermarkedImage, lTimeElapsed, lResult] = LSBEmbedImage(lHostImage, lWatermarkImage);
                        lEmbeddedImage = lHostImage.embed(lWatermarkImage, 'LSB');
                        lEmbedded = lEmbeddedImage.matrix;
                        lResult = true;
                        app.embedTab_label_algorithm.Text = "LSB";
                    case '2'
                        % embed using DCT
                        [lWatermarkedImage, lTimeElapsed, lResult] = DCTEmbed(lHost, lWatermark);
                        lEmbedded = lWatermarkedImage;
                        app.embedTab_label_algorithm.Text = "DCT";
                    case '3'
                        % embed using SVD
                        %[lWatermarkedImage, lTimeElapsed, lResult] = SVDEmbed(lHost, lWatermark);
                        %[lEmbeddedImage, lTimeElapsed, lResult] = SVDEmbedImage(lHostImage, lWatermarkImage);
                        lEmbeddedImage = lHostImage.embed(lWatermarkImage, 'SVD');
                        lEmbedded = lEmbeddedImage.matrix;
                        app.embedTab_label_algorithm.Text = "SVD";
                    otherwise
                        % throw some error
                        disp('Error: EmbedWatermarkButtonPushed');
                end
                
                lTimeElapsed_total = (lTimeElapsed_total + lTimeElapsed);
                lTimeElapsedArr(1,i) = lTimeElapsed;
            end
            
            if lResult == true
                %                     imshow(lWatermarkedImage, 'Parent', app.embedTab_display_watermarkedimage);              % display image on axis
%                 app.embedTab_label_averagetime.Text = string(lTimeElapsed_total/lTrials);                % update average time display
%                 app.label_totaltime_embed.Text = string(lTimeElapsed_total);                          % update total time display
%                 app.fEmbedded = AppImage(lEmbedded, 'Embedded');
                app.fEmbedded = lEmbeddedImage;
                if updateStats(app) == true
                    disp('Stats updated: button_embedWatermark_embedPushed');
                end
                swVal = app.switch_advancedStats_embed.Value;
                disp(string(swVal));
                if embedTabRefresh(app) == true
                    % something changed
                    disp('Embed Tab: Refreshed');
                else
                    % nothing changed
                    disp('Embed Tab: No Change');
                end
                
                if strcmp(swVal, 'On') == 1
                    % plot elapsed time on graph
                    figure('Name', 'Embedding time per trial'),
                    plot(lTimeElapsedArr), title('Embedding Time'), xlabel('Trial'), ylabel('Time (sec)');
                    
                end
            else
                app.embedTab_label_averagetime.Text = "failed";
                disp('Error: EmbedWatermarkButtonPushed: Embedding function returned false');
            end
        end

        % Value changed function: dropdown_extract
        function dropdown_extractValueChanged(app, event)
            value = app.dropdown_extract.Value;
            switch value
                case '1'
                    % describe LSB decryption
                    app.textbox_extract.Value = 'Watermark extraction using the LSB technique involves extracting the least significant bit value of an image, which contains the watermark';
                case '2'
                    % describe DCT decryption
                    app.textbox_extract.Value = 'TODO';
                case '3'
                    % describe SVD decryption
                    app.textbox_extract.Value = 'TODO';
                otherwise
                    % error
                    app.textbox_extract.Value = 'Please select a valid extraction technique from the dropdown';
            end
        end

        % Button pushed function: button_extractWatermark_extract
        function button_extractWatermark_extractPushed(app, event)
            lEmbeddedImage = app.fEmbedded;
            lEmbedded = app.fEmbedded.matrix;
            lWatermark = app.fWatermark.matrix;
            
            value = app.dropdown_extract.Value;
            lExtracted = 0;
            lResult = 0;
            lElapsedTime = 0;
            switch value
                case '1'
                    % extract using LSB
                    %[lExtracted, lElapsedTime, lResult] = BitplaneExtract(lEmbedded);
                    [lExtractedImage, lElapsedTime, lResult] = LSBExtract(lEmbeddedImage);
                    lExtracted = lExtractedImage.matrix;
                    app.label_algorithm_extract.Text = 'LSB';
                case '2'
                    % extract using DCT
                    [lExtracted, lElapsedTime, lResult] = DCTExtract(lEmbedded);
                    app.label_algorithm_extract.Text = 'DCT';
                case '3'
                    % extract using SVD
                    [lExtracted, lElapsedTime, lResult] = SVDExtract(lEmbedded, lWatermark);
                    app.label_algorithm_extract.Text = 'SVD';
                otherwise
                    % output error to terminal
                    disp('Error: button_extractWatermark_extractPushed FAILED');
            end
            if lResult == true
                app.label_time_extract.Text = string(lElapsedTime);
                app.fExtracted = AppImage(lExtracted);
                %                 app.gExtracted = lExtracted;
                %                     imshow(lExtracted, 'Parent', app.display_extracted_extract);
                if updateStats(app) == true
                    disp('Stats updated: button_extractWatermark_extractPushed');
                end
            else
                disp('Something went wrong, check your configuration and try again');
            end
            % refresh screen
            if extractTabRefresh(app) == true
                disp('Extract Tab Refreshed');
            end
        end

        % Button pushed function: button_saveEmbedded_embed
        function button_saveEmbedded_embedButtonPushed(app, event)
            fileType = app.dropdown_fileType_embed.Value;
            lEmbeddedImage = app.fEmbedded;
            lImage = uint8(lEmbeddedImage.matrix);
            %             lImage = uint8(app.gEmbedded);
            switch fileType
                case '0'
                    % no file type selected
                    disp('Error: Save Button Pushed');
                    disp('No file type selected');
                case '1'
                    % JPEG
                    imwrite(lImage, 'watermarkedImage.jpeg');
                    disp('Watermarked Image Saved Successfully: jpeg');
                case '2'
                    % TIFF
                    imwrite(lImage, 'watermarkedImage.tif');
                    disp('Watermarked Image Saved Successfully: tif');
                case '3'
                    % BMP
                    imwrite(lImage, 'watermarkedImage.bmp');
                    disp('Watermarked Image Saved Successfully: bmp');
                case '4'
                    % PNG
                    imwrite(lImage, 'watermarkedImage.png');
                    disp('Watermarked Image Saved Successfully: png');
                otherwise
                    % error
                    disp('Error: save_embedButtonPushed critical error');
            end
        end

        % Button pushed function: button_loadEmbedded_extract
        function button_loadEmbedded_extractButtonPushed(app, event)
            lResult = loadAppImage(app, 'Embedded');
            if lResult == true
                if embeddedTabRefresh(app) == true
                    disp('Embedded tab refreshed');
                end
            else
                % print error to terminal
                disp('Error: button_loadEmbedded_extractButtonPushed - FAILED. loadAppImage returned false');
            end
        end

        % Button pushed function: button_jpegAttack_attack
        function button_jpegAttack_attackButtonPushed(app, event)
            try
                lAttackedImage = jpegAttack(app);
                if lAttackedImage == 0
                    disp('Error: jpegAttack has failed, please load a suitable image');
                else
                    imshow(lAttackedImage, [], 'Parent', app.display_attackedImage_attack);
                    app.fImage2 = AppImage(lAttackedImage);
                end
            catch
                disp('Error: jpegAttack has encountered a critical error');
            end
        end

        % Button pushed function: button_saveExtracted_extract
        function button_saveExtracted_extractButtonPushed(app, event)
            fileType = app.dropdown_fileType_extract.Value;
            lExtractedImage = app.fExtracted;
            lImage = lExtractedImage.matrix;
            %             lImage = uint8(app.gExtracted);
            switch fileType
                case '0'
                    % no file type selected
                    disp('Error: Save Button Pushed');
                    disp('No file type selected');
                case '1'
                    % JPEG
                    imwrite(lImage, 'extractedWatermark.jpeg');
                    disp('Watermarked Image Saved Successfully: jpeg');
                case '2'
                    % TIFF
                    imwrite(lImage, 'extractedWatermark.tif');
                    disp('Watermarked Image Saved Successfully: tif');
                case '3'
                    % BMP
                    imwrite(lImage, 'extractedWatermark.bmp');
                    disp('Watermarked Image Saved Successfully: bmp');
                case '4'
                    % PNG
                    imwrite(lImage, 'extractedWatermark.png');
                    disp('Watermarked Image Saved Successfully: png');
                otherwise
                    % error
                    disp('Error: save_extractButtonPushed critical error');
            end
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab.Title;
            if updateStats(app) == true
                disp('updateStats: TabGroupSelectionChanged');
            end
            if isequal(selectedTab, 'Embed')
                % refresh embedtab
                disp('EmbedTab selected');
                %                 imshow(app.gWatermark, 'Parent', app.embedTab_display_watermark);
                %                 imshow(app.gHost, 'Parent', app.embedTab_display_hostimage);
                if embedTabRefresh(app) == true
                    disp('TabChange: embed tab refreshed');
                end
            end
            if isequal(selectedTab, 'Extract')
                % refresh extracttab
                disp('ExtractTab selected')
                %                 imshow(app.gEmbedded, 'Parent', app.display_watermarkedimage_extract);
                %                 imshow(app.gExtracted, 'Parent', app.display_extracted_extract);
                if extractTabRefresh(app) == true
                    disp('TabChange: extract tab refreshed');
                end
            end
            if isequal(selectedTab, 'Attacks')
                % refresh attackstab
                disp('AttacksTab selected')
                imshow(app.fEmbedded.matrix, 'Parent', app.display_embeddedImage_attack);
            end
            if isequal(selectedTab, 'Comparison')
                % refresh comparisiontab
                disp('ComparisonTab Selected');
                imshow(app.fImage1.matrix, 'Parent', app.comparisonTab_display_image1);
                imshow(app.fImage2.matrix, 'Parent', app.comparisonTab_display_image2);
            end
            if isequal(selectedTab, 'Home')
                % refresh all
                refreshAllTabs(app);
            end
        end

        % Button pushed function: button_saveAttacked_attack
        function button_saveAttacked_attackButtonPushed(app, event)
            
        end

        % Button pushed function: button_loadOriginal_attack
        function button_loadOriginal_attackButtonPushed(app, event)
            if loadAppImage(app, 'Embedded') == false
                disp('Error: button_loadOriginal_attackButtonPushed - FAILED: loadAppImage returned false');
            else
                imshow(app.gEmbedded, 'Parent', app.display_embeddedImage_attack);
            end
        end

        % Button pushed function: comparisonTab_button_loadImage1
        function comparisonTab_button_loadImage1ButtonPushed(app, event)
            if loadAppImage(app, 'Image1') == false
                disp('Error: button_loadAppImage1_comparisonButtonPushed - FAILED: loadAppImage returned false');
            else
                imshow(app.gImage1, 'Parent', app.comparisonTab_display_image1);
            end
        end

        % Button pushed function: comparisonTab_button_loadImage2
        function comparisonTab_button_loadImage2ButtonPushed(app, event)
            if loadAppImage(app, 'Image2') == false
                disp('Error: button_loadAppImage2_comparisonButtonPushed - FAILED: loadAppImage returned false');
            else
                imshow(app.gImage2, 'Parent', app.comparisonTab_display_image2);
            end
        end

        % Button pushed function: button_compare_attack
        function button_compare_attackButtonPushed(app, event)
            imshow(app.display_attackedImage_attack.Children);
        end

        % Button pushed function: comparisonTab_button_loadlocal1
        function comparisonTab_button_loadlocal1ButtonPushed(app, event)
            lSelected = app.comparisonTab_dropdown_loadlocal1.Value;
            app.comparisonTab_label_loadlocal1.Text = '';
            lOutputText = "Can't find local image: ";
            switch lSelected
                case '1'
                    % Watermark
                    if isempty(app.fWatermark.matrix) == false
                        app.fImage1 = app.fWatermark;
                    else
                        % display error
                        app.comparisonTab_label_loadlocal2.Text = strcat(lOutputText, 'watermark');
                    end
                case '2'
                    % Host
                    if isempty(app.fHost.matrix) == false
                        app.fImage1 = app.fHost;
                    else
                        % display error
                        app.comparisonTab_label_loadlocal2.Text = strcat(lOutputText, 'host');
                    end
                case '3'
                    % Embedded
                    if isempty(app.fEmbedded.matrix) == false
                        app.fImage1 = app.fEmbedded;
                    else
                        % display error
                        app.comparisonTab_label_loadlocal2.Text = strcat(lOutputText, 'embedded');
                    end
                case '4'
                    % Extracted
                    if isempty(app.fExtracted) == false
                        app.fImage1 = app.fExtracted;
                    else
                        % display error
                        app.comparisonTab_label_loadlocal2.Text = strcat(lOutputText, 'extracted');
                    end
                otherwise
                    % display error
                    disp('Error: button_loadlocal1_comparisonButtonPushed - FAILED: lSelected out of range');
            end
            if isempty(app.fImage1.matrix) == false
                imshow(app.fImage1.matrix, 'Parent', app.comparisonTab_display_image1);
            end
        end

        % Button pushed function: comparisonTab_button_loadlocal2
        function comparisonTab_button_loadlocal2ButtonPushed(app, event)
            lSelected = app.comparisonTab_dropdown_loadlocal2.Value;
            app.comparisonTab_label_loadlocal2.Text = '';
            lOutputText = "Can't find local image: ";
            switch lSelected
                case '1'
                    % Watermark
                    if isempty(app.fWatermark.matrix) == false
                        app.fImage2 = app.fWatermark;
                    else
                        % display error
                        app.comparisonTab_label_loadlocal2.Text = strcat(lOutputText, 'watermark');
                    end
                case '2'
                    % Host
                    if isempty(app.fHost.matrix) == false
                        app.fImage2 = app.fHost;
                    else
                        % display error
                        app.comparisonTab_label_loadlocal2.Text = strcat(lOutputText, 'host');
                    end
                case '3'
                    % Embedded
                    if isempty(app.fEmbedded.matrix) == false
                        app.fImage2 = app.fEmbedded;
                    else
                        % display error
                        app.comparisonTab_label_loadlocal2.Text = strcat(lOutputText, 'embedded');
                    end
                case '4'
                    % Extracted
                    if isempty(app.fExtracted.matrix) == false
                        app.fImage2 = app.fExtracted;
                    else
                        % display error
                        app.comparisonTab_label_loadlocal2.Text = strcat(lOutputText, 'extracted');
                    end
                otherwise
                    % display error
                    disp('Error: button_loadlocal1_comparisonButtonPushed - FAILED: lSelected out of range');
            end
            if isempty(app.fImage2.matrix) == false
                imshow(app.fImage2.matrix, 'Parent', app.comparisonTab_display_image2);
            end
        end

        % Button pushed function: comparisonTab_button_compare
        function comparisonTab_button_compareButtonPushed(app, event)
            lImage1 = app.fImage1.matrix;
            lImage2 = app.fImage2.matrix;
            if isempty(lImage1) == false
                if isempty(lImage2) == false
                    dif = directCompare(lImage1, lImage2);
                    imshow(dif, [], 'Parent', app.comparisonTab_display_output);
                end
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.HomeTab = uitab(app.TabGroup);
            app.HomeTab.Title = 'Home';
            app.WatermarkerLabel = uilabel(app.HomeTab);
            app.WatermarkerLabel.Text = 'Watermarker';
            app.homeTab_label_loading = uilabel(app.HomeTab);
            app.homeTab_label_loading.Text = 'Loading...';
            app.EmbedTab = uitab(app.TabGroup);
            app.EmbedTab.Title = 'Embed';
            app.embedTab_display_watermark = uiaxes(app.EmbedTab);
            title(app.embedTab_display_watermark, 'Watermark')
            app.embedTab_display_hostimage = uiaxes(app.EmbedTab);
            title(app.embedTab_display_hostimage, 'Host Image')
            app.embedTab_display_watermarkedimage = uiaxes(app.EmbedTab);
            title(app.embedTab_display_watermarkedimage, 'Watermarked Image')
            app.embedTab_textbox = uitextarea(app.EmbedTab);
            app.embedTab_textbox.Value = {'Please select an embedding technique from the dropdown menu'};
            app.embedTab_button_loadWatermark = uibutton(app.EmbedTab, 'push');
            app.embedTab_button_loadWatermark.ButtonPushedFcn = createCallbackFcn(app, @embedTab_button_loadWatermarkPushed, true);
            app.embedTab_button_loadWatermark.Text = 'Load Watermark';
            app.embedTab_button_loadHost = uibutton(app.EmbedTab, 'push');
            app.embedTab_button_loadHost.ButtonPushedFcn = createCallbackFcn(app, @embedTab_button_loadHostPushed, true);
            app.embedTab_button_loadHost.Text = 'Load Host Image';
            app.embedTab_button_embedWatermark = uibutton(app.EmbedTab, 'push');
            app.embedTab_button_embedWatermark.ButtonPushedFcn = createCallbackFcn(app, @embedTab_button_embedWatermarkPushed, true);
            app.embedTab_button_embedWatermark.Text = 'Embed Watermark';
            app.embedTab_AverageembeddingtimesecLabel = uilabel(app.EmbedTab);
            app.embedTab_AverageembeddingtimesecLabel.Text = 'Average embedding time (sec):';
            app.embedTab_label_averagetime = uilabel(app.EmbedTab);
            app.embedTab_label_averagetime.Text = '0';
            app.embedTab_UsingLabel = uilabel(app.EmbedTab);
            app.embedTab_UsingLabel.Text = 'Using: ';
            app.embedTab_label_algorithm = uilabel(app.EmbedTab);
            app.embedTab_label_algorithm.Text = '<NA>';
            app.EmbeddingAlgorithmDropDownLabel = uilabel(app.EmbedTab);
            app.EmbeddingAlgorithmDropDownLabel.Text = 'Embedding Algorithm';
            app.embedTab_dropdown_embeddingAlgorithm = uidropdown(app.EmbedTab);
            app.embedTab_dropdown_embeddingAlgorithm.Items = {'SELECT', 'LSB', 'DCT', 'SVD'};
            app.embedTab_dropdown_embeddingAlgorithm.ItemsData = {'0', '1', '2', '3'};
            app.embedTab_dropdown_embeddingAlgorithm.ValueChangedFcn = createCallbackFcn(app, @dropdown_embedValueChanged, true);
            app.embedTab_dropdown_embeddingAlgorithm.Value = '0';
            app.button_saveEmbedded_embed = uibutton(app.EmbedTab, 'push');
            app.button_saveEmbedded_embed.ButtonPushedFcn = createCallbackFcn(app, @button_saveEmbedded_embedButtonPushed, true);
            app.button_saveEmbedded_embed.Text = 'Save';
            app.FileTypeDropDownLabel = uilabel(app.EmbedTab);
            app.FileTypeDropDownLabel.Text = 'File Type';
            app.dropdown_fileType_embed = uidropdown(app.EmbedTab);
            app.dropdown_fileType_embed.Items = {'SELECT', 'JPEG', 'TIFF', 'BMP', 'PNG'};
            app.dropdown_fileType_embed.ItemsData = {'0', '1', '2', '3', '4'};
            app.dropdown_fileType_embed.Value = '0';
            app.TrialsLabel = uilabel(app.EmbedTab);
            app.TrialsLabel.Text = 'Trials';
            app.spinner_trials_embed = uispinner(app.EmbedTab);
            app.spinner_trials_embed.Limits = [1 Inf];
            app.spinner_trials_embed.Value = 1;
            app.TotalembeddingtimesecLabel = uilabel(app.EmbedTab);
            app.TotalembeddingtimesecLabel.Text = 'Total embedding time (sec):';
            app.label_totaltime_embed = uilabel(app.EmbedTab);
            app.label_totaltime_embed.Text = '0';
            app.ShowAdvancedStatsSwitchLabel = uilabel(app.EmbedTab);
            app.ShowAdvancedStatsSwitchLabel.Text = 'Show Advanced Stats';
            app.switch_advancedStats_embed = uiswitch(app.EmbedTab, 'toggle');
            app.TilingStrengthLabel = uilabel(app.EmbedTab);
            app.TilingStrengthLabel.Text = 'Tiling Strength';
            app.knob_embeddingStrength_embed = uiknob(app.EmbedTab, 'discrete');
            app.knob_embeddingStrength_embed.Items = {'Off', 'Low', 'High'};
            app.knob_embeddingStrength_embed.ItemsData = {'1', '2', '3'};
            app.knob_embeddingStrength_embed.Value = '1';
            app.label_watermarkSize_embed = uilabel(app.EmbedTab);
            app.label_watermarkSize_embed.Text = 'nxn';
            app.label_hostSize_embed = uilabel(app.EmbedTab);
            app.label_hostSize_embed.Text = 'nxn';
            app.PixelsLabel = uilabel(app.EmbedTab);
            app.PixelsLabel.Text = 'Pixels';
            app.PixelsLabel_2 = uilabel(app.EmbedTab);
            app.PixelsLabel_2.Text = 'Pixels';
            app.ExtractTab = uitab(app.TabGroup);
            app.ExtractTab.Title = 'Extract';
            app.display_extracted_extract = uiaxes(app.ExtractTab);
            title(app.display_extracted_extract, 'Extracted Watermark')
            app.display_watermarkedimage_extract = uiaxes(app.ExtractTab);
            title(app.display_watermarkedimage_extract, 'Watermarked Image')
            app.button_extractWatermark_extract = uibutton(app.ExtractTab, 'push');
            app.button_extractWatermark_extract.ButtonPushedFcn = createCallbackFcn(app, @button_extractWatermark_extractPushed, true);
            app.button_extractWatermark_extract.Text = 'Extract Watermark';
            app.textbox_extract = uitextarea(app.ExtractTab);
            app.textbox_extract.Value = {'Please select an extraction technique from the dropdown menu'};
            app.ExtractingAlgorithmLabel = uilabel(app.ExtractTab);
            app.ExtractingAlgorithmLabel.Text = 'Extracting Algorithm';
            app.dropdown_extract = uidropdown(app.ExtractTab);
            app.dropdown_extract.Items = {'SELECT', 'LSB', 'DCT', 'SVD'};
            app.dropdown_extract.ItemsData = {'0', '1', '2', '3'};
            app.dropdown_extract.ValueChangedFcn = createCallbackFcn(app, @dropdown_extractValueChanged, true);
            app.dropdown_extract.Value = '0';
            app.button_loadEmbedded_extract = uibutton(app.ExtractTab, 'push');
            app.button_loadEmbedded_extract.ButtonPushedFcn = createCallbackFcn(app, @button_loadEmbedded_extractButtonPushed, true);
            app.button_loadEmbedded_extract.Text = 'Load Embedded Image';
            app.ExtractiontimesecLabel = uilabel(app.ExtractTab);
            app.ExtractiontimesecLabel.Text = 'Extraction time (sec): ';
            app.label_time_extract = uilabel(app.ExtractTab);
            app.label_time_extract.Text = '0';
            app.UsingLabel_2 = uilabel(app.ExtractTab);
            app.UsingLabel_2.Text = 'Using: ';
            app.label_algorithm_extract = uilabel(app.ExtractTab);
            app.label_algorithm_extract.Text = '<NA>';
            app.result_savewatermark_extract = uilabel(app.ExtractTab);
            app.result_savewatermark_extract.Text = '';
            app.button_saveExtracted_extract = uibutton(app.ExtractTab, 'push');
            app.button_saveExtracted_extract.ButtonPushedFcn = createCallbackFcn(app, @button_saveExtracted_extractButtonPushed, true);
            app.button_saveExtracted_extract.Text = 'Save';
            app.FileTypeDropDownLabel_2 = uilabel(app.ExtractTab);
            app.FileTypeDropDownLabel_2.Text = 'File Type';
            app.dropdown_fileType_extract = uidropdown(app.ExtractTab);
            app.dropdown_fileType_extract.Items = {'SELECT', 'JPEG', 'TIFF', 'BMP', 'PNG'};
            app.dropdown_fileType_extract.ItemsData = {'0', '1', '2', '3', '4'};
            app.dropdown_fileType_extract.Value = '0';
            app.label_embeddedSize_extract = uilabel(app.ExtractTab);
            app.label_embeddedSize_extract.Text = 'nxn';
            app.PixelsLabel_3 = uilabel(app.ExtractTab);
            app.PixelsLabel_3.Text = 'Pixels';
            app.label_extractedSize_extract = uilabel(app.ExtractTab);
            app.label_extractedSize_extract.Text = 'nxn';
            app.PixelsLabel_4 = uilabel(app.ExtractTab);
            app.PixelsLabel_4.Text = 'Pixels';
            app.AttacksTab = uitab(app.TabGroup);
            app.AttacksTab.Title = 'Attacks';
            app.button_jpegAttack_attack = uibutton(app.AttacksTab, 'push');
            app.button_jpegAttack_attack.ButtonPushedFcn = createCallbackFcn(app, @button_jpegAttack_attackButtonPushed, true);
            app.button_jpegAttack_attack.Text = 'Select';
            app.JPEGCompressionLabel = uilabel(app.AttacksTab);
            app.JPEGCompressionLabel.Text = 'JPEG Compression';
            app.button_noiseAttack_attack = uibutton(app.AttacksTab, 'push');
            app.button_noiseAttack_attack.Text = 'Select';
            app.NoiseAttackLabel = uilabel(app.AttacksTab);
            app.NoiseAttackLabel.Text = 'Noise Attack';
            app.button_croppingAttack_attack = uibutton(app.AttacksTab, 'push');
            app.button_croppingAttack_attack.Text = 'Select';
            app.CroppingLabel = uilabel(app.AttacksTab);
            app.CroppingLabel.Text = 'Cropping';
            app.display_attackedImage_attack = uiaxes(app.AttacksTab);
            title(app.display_attackedImage_attack, 'Attacked')
            app.display_embeddedImage_attack = uiaxes(app.AttacksTab);
            title(app.display_embeddedImage_attack, 'Original')
            app.button_loadOriginal_attack = uibutton(app.AttacksTab, 'push');
            app.button_loadOriginal_attack.ButtonPushedFcn = createCallbackFcn(app, @button_loadOriginal_attackButtonPushed, true);
            app.button_loadOriginal_attack.Text = 'Load image';
            app.button_saveAttacked_attack = uibutton(app.AttacksTab, 'push');
            app.button_saveAttacked_attack.ButtonPushedFcn = createCallbackFcn(app, @button_saveAttacked_attackButtonPushed, true);
            app.button_saveAttacked_attack.Text = 'Save Image';
            app.button_compare_attack = uibutton(app.AttacksTab, 'push');
            app.button_compare_attack.ButtonPushedFcn = createCallbackFcn(app, @button_compare_attackButtonPushed, true);
            app.button_compare_attack.Text = 'Compare';
            app.ComparisonTab = uitab(app.TabGroup);
            app.ComparisonTab.Title = 'Comparison';
            app.comparisonTab_display_image1 = uiaxes(app.ComparisonTab);
            app.comparisonTab_display_image2 = uiaxes(app.ComparisonTab);
            app.comparisonTab_button_loadImage1 = uibutton(app.ComparisonTab, 'push');
            app.comparisonTab_button_loadImage1.ButtonPushedFcn = createCallbackFcn(app, @comparisonTab_button_loadImage1ButtonPushed, true);
            app.comparisonTab_button_loadImage1.Text = 'Load from file';
            app.comparisonTab_button_loadImage2 = uibutton(app.ComparisonTab, 'push');
            app.comparisonTab_button_loadImage2.ButtonPushedFcn = createCallbackFcn(app, @comparisonTab_button_loadImage2ButtonPushed, true);
            app.comparisonTab_button_loadImage2.Text = 'Load from file';
            app.LocalImagesDropDownLabel = uilabel(app.ComparisonTab);
            app.LocalImagesDropDownLabel.Text = 'Local Images';
            app.comparisonTab_dropdown_loadlocal1 = uidropdown(app.ComparisonTab);
            app.comparisonTab_dropdown_loadlocal1.Items = {'Watermark', 'Host', 'Embedded', 'Extracted'};
            app.comparisonTab_dropdown_loadlocal1.ItemsData = {'1', '2', '3', '4'};
            app.comparisonTab_dropdown_loadlocal1.Value = '1';
            app.comparisonTab_button_loadlocal1 = uibutton(app.ComparisonTab, 'push');
            app.comparisonTab_button_loadlocal1.ButtonPushedFcn = createCallbackFcn(app, @comparisonTab_button_loadlocal1ButtonPushed, true);
            app.comparisonTab_button_loadlocal1.Text = 'Load';
            app.LocalImagesDropDown_2Label = uilabel(app.ComparisonTab);
            app.LocalImagesDropDown_2Label.Text = 'Local Images';
            app.comparisonTab_dropdown_loadlocal2 = uidropdown(app.ComparisonTab);
            app.comparisonTab_dropdown_loadlocal2.Items = {'Watermark', 'Host', 'Embedded', 'Extracted'};
            app.comparisonTab_dropdown_loadlocal2.ItemsData = {'1', '2', '3', '4'};
            app.comparisonTab_dropdown_loadlocal2.Value = '1';
            app.comparisonTab_button_loadlocal2 = uibutton(app.ComparisonTab, 'push');
            app.comparisonTab_button_loadlocal2.ButtonPushedFcn = createCallbackFcn(app, @comparisonTab_button_loadlocal2ButtonPushed, true);
            app.comparisonTab_button_loadlocal2.Text = 'Load';
            app.comparisonTab_label_loadlocal1 = uilabel(app.ComparisonTab);
            app.comparisonTab_label_loadlocal1.Text = '';
            app.comparisonTab_label_loadlocal2 = uilabel(app.ComparisonTab);
            app.comparisonTab_label_loadlocal2.Text = '';
            app.comparisonTab_button_compare = uibutton(app.ComparisonTab, 'push');
            app.comparisonTab_button_compare.ButtonPushedFcn = createCallbackFcn(app, @comparisonTab_button_compareButtonPushed, true);
            app.comparisonTab_button_compare.Text = 'Compare';
            app.comparisonTab_display_output = uiaxes(app.ComparisonTab);
            app.stats = uilabel(app.UIFigure);
            app.stats.Enable = 'off';
            app.stats.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = watermarker2

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end